classdef mainWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TechnologySelectionPanel     matlab.ui.container.Panel
        TechnologyNodeDropDownLabel  matlab.ui.control.Label
        TechnologyNodeDropDown       matlab.ui.control.DropDown
        CircuitSelectionPanel        matlab.ui.container.Panel
        CircuitDropDownLabel         matlab.ui.control.Label
        CircuitDropDown              matlab.ui.control.DropDown
        StartButton                  matlab.ui.control.Button
        OutputSettingsPanel          matlab.ui.container.Panel
        AverageCouplingLengthPanel   matlab.ui.container.Panel
        DC1CheckBox                  matlab.ui.control.CheckBox
        DC2CheckBox                  matlab.ui.control.CheckBox
        regSCheckBox                 matlab.ui.control.CheckBox
        regCCheckBox                 matlab.ui.control.CheckBox
        PlotButtonGroup              matlab.ui.container.ButtonGroup
        AllButton                    matlab.ui.control.RadioButton
        TopButton                    matlab.ui.control.RadioButton
        NoneButton                   matlab.ui.control.RadioButton
        DispersionCrurvesPanel       matlab.ui.container.Panel
        DC1CheckBox_2                matlab.ui.control.CheckBox
        DC2CheckBox_2                matlab.ui.control.CheckBox
        regSCheckBox_2               matlab.ui.control.CheckBox
        regCCheckBox_2               matlab.ui.control.CheckBox
        DesignSettingsPanel          matlab.ui.container.Panel
        regSCheckBox_3               matlab.ui.control.CheckBox
        regCCheckBox_3               matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % add simulation path with correct separator
            %addpath(genpath(['simulation' filesep]))
            addpath(['..' filesep 'simulation' filesep])
            model = app.TechnologyNodeDropDown.Value;
            circuit = app.CircuitDropDown.Value;
            plot_info = "plot_top";
            if app.AllButton.Value
                plot_info = "plot_all";
            elseif app.NoneButton.Value
                plot_info = "no_plot";
            end
            opt_parameters = {};
            for element = app.AverageCouplingLengthPanel.Children'
                if element.Value
                    opt_parameters{end+1} = element.Text+"_Lc_avg";
                end
            end
            
            for element = app.DispersionCrurvesPanel.Children'
                if element.Value
                    opt_parameters{end+1} = element.Text+"_dispersion_curves";
                end
            end
            
            for element = app.DesignSettingsPanel.Children'
                if ~element.Value
                    opt_parameters{end+1} = "DC_without_"+element.Text;
                end
            end

            Generic_simulator
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TechnologySelectionPanel = uipanel(app.UIFigure);
            app.TechnologySelectionPanel.Title = 'Technology Selection';
            app.TechnologyNodeDropDownLabel = uilabel(app.TechnologySelectionPanel);
            app.TechnologyNodeDropDownLabel.Text = 'Technology Node';
            app.TechnologyNodeDropDown = uidropdown(app.TechnologySelectionPanel);
            app.TechnologyNodeDropDown.Items = {'YIG 100nm', 'YIG 30nm'};
            app.TechnologyNodeDropDown.Value = 'YIG 100nm';
            app.CircuitSelectionPanel = uipanel(app.UIFigure);
            app.CircuitSelectionPanel.Title = 'Circuit Selection';
            app.CircuitDropDownLabel = uilabel(app.CircuitSelectionPanel);
            app.CircuitDropDownLabel.Text = 'Circuit';
            app.CircuitDropDown = uidropdown(app.CircuitSelectionPanel);
            app.CircuitDropDown.Items = {'AND(A,B)', 'AND4(A,B,C,D)', 'N-bit Carry_skip_adder(A,B,C=Carry_in)', 'FA(A,B,C)', 'HA(A,B)', 'Mux2to1(A,B,C=sel)', 'NOT(A)', 'OR(A,B)', 'N-bit RCA(A,B,C=Carry_in)', 'waveguide(A,Lw)', 'XOR(A,B)'};
            app.CircuitDropDown.Value = 'AND(A,B)';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.OutputSettingsPanel = uipanel(app.UIFigure);
            app.OutputSettingsPanel.Title = 'Output Settings';
            app.AverageCouplingLengthPanel = uipanel(app.OutputSettingsPanel);
            app.AverageCouplingLengthPanel.Title = 'Average Coupling Length';
            app.DC1CheckBox = uicheckbox(app.AverageCouplingLengthPanel);
            app.DC1CheckBox.Text = 'DC1';
            app.DC2CheckBox = uicheckbox(app.AverageCouplingLengthPanel);
            app.DC2CheckBox.Text = 'DC2';
            app.regSCheckBox = uicheckbox(app.AverageCouplingLengthPanel);
            app.regSCheckBox.Text = 'regS';
            app.regCCheckBox = uicheckbox(app.AverageCouplingLengthPanel);
            app.regCCheckBox.Text = 'regC';
            app.PlotButtonGroup = uibuttongroup(app.OutputSettingsPanel);
            app.PlotButtonGroup.Title = 'Plot';
            app.AllButton = uiradiobutton(app.PlotButtonGroup);
            app.AllButton.Text = 'All';
            app.TopButton = uiradiobutton(app.PlotButtonGroup);
            app.TopButton.Text = 'Top';
            app.TopButton.Value = true;
            app.NoneButton = uiradiobutton(app.PlotButtonGroup);
            app.NoneButton.Text = 'None';
            app.DispersionCrurvesPanel = uipanel(app.UIFigure);
            app.DispersionCrurvesPanel.Title = 'Dispersion Crurves';
            app.DC1CheckBox_2 = uicheckbox(app.DispersionCrurvesPanel);
            app.DC1CheckBox_2.Text = 'DC1';
            app.DC2CheckBox_2 = uicheckbox(app.DispersionCrurvesPanel);
            app.DC2CheckBox_2.Text = 'DC2';
            app.regSCheckBox_2 = uicheckbox(app.DispersionCrurvesPanel);
            app.regSCheckBox_2.Text = 'regS';
            app.regCCheckBox_2 = uicheckbox(app.DispersionCrurvesPanel);
            app.regCCheckBox_2.Text = 'regC';
            app.DesignSettingsPanel = uipanel(app.UIFigure);
            app.DesignSettingsPanel.Title = 'Design Settings';
            app.regSCheckBox_3 = uicheckbox(app.DesignSettingsPanel);
            app.regSCheckBox_3.Text = 'regS';
            app.regSCheckBox_3.Value = true;
            app.regCCheckBox_3 = uicheckbox(app.DesignSettingsPanel);
            app.regCCheckBox_3.Text = 'regC';
            app.regCCheckBox_3.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end